classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ZaawansowaneopcjesterowaniaPanel  matlab.ui.container.Panel
        WczytajliststerowazMatlabamacierznx4Label  matlab.ui.control.Label
        nazwazmiennejEditField       matlab.ui.control.EditField
        nazwazmiennejEditFieldLabel  matlab.ui.control.Label
        WczytajButton                matlab.ui.control.Button
        SterowanieaktywneLamp        matlab.ui.control.Lamp
        SterowanieaktywneLampLabel   matlab.ui.control.Label
        PredkoscPozycjaSwitch        matlab.ui.control.Switch
        OdomPozycjaSwitch            matlab.ui.control.Switch
        ZaawansowaneOdomButton       matlab.ui.control.Button
        ZaawansowaneVelButton        matlab.ui.control.Button
        StanDronaTextArea            matlab.ui.control.TextArea
        StandronaTextArea_2Label     matlab.ui.control.Label
        RozlaczButton                matlab.ui.control.Button
        EksportujObrazButton         matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        WyslijButton                 matlab.ui.control.Button
        VelUITable                   matlab.ui.control.Table
        OdomUITable                  matlab.ui.control.Table
        AwariaButton                 matlab.ui.control.Button
        LadowanieButton              matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        PolaczButton                 matlab.ui.control.Button
        PortEditField                matlab.ui.control.NumericEditField
        PortEditFieldLabel           matlab.ui.control.Label
        IPMasteraEditField           matlab.ui.control.EditField
        IPmasteraEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        % ROSowe publishery i subscribery
        takeoff_pub
        land_pub
        emergency_pub
        cmd_vel_pub
        
        odometry_sub
        position_sub
        image_raw_sub
        battery_state_sub
        wifi_state_sub
        flying_state_sub

        % parametry kontrolera PID
        Kp = 0.8; % 1;
        Ki = 0; % 0.01;
        Kd = 0; % 0.5;

        % docelowe pozycje
        goal_positions = zeros(0, 4);

        % poprzednia wartość i suma uchybu
        diff_prev = [0 0 0 0];
        diff_sum = [0 0 0 0];

        % czas ostatniej wiadomości z pozycją
        last_msg_timestamp = 0;

        % stringi ze stanem drona
        battery_state_string
        wifi_state_string
        flying_state_string
    end
    
    methods (Access = private)
        
        function odometry_callback(app, ~, message)
            if strcmp(app.OdomPozycjaSwitch.Value, 'Odometria')
                % pozycja
                pos = [0 0 0];
                pos(1) = message.Pose.Pose.Position.X;
                pos(2) = message.Pose.Pose.Position.Y;
                pos(3) = message.Pose.Pose.Position.Z;
                % orientacja
                w = message.Pose.Pose.Orientation.W;
                x = message.Pose.Pose.Orientation.X;
                y = message.Pose.Pose.Orientation.Y;
                z = message.Pose.Pose.Orientation.Z;
                % roll pitch yaw
                rot = quat2eul([w x y z]);
                rot = rot(end:-1:1);
                
                odometry = [pos, rot];
                app.OdomUITable.Data = odometry;
            end
        end
        
        function position_callback(app, ~, message)
            % pozycja
            pos = [0 0 0];
            pos(1) = message.Pose.Pose.Position.X;
            pos(2) = message.Pose.Pose.Position.Y;
            pos(3) = message.Pose.Pose.Position.Z;
            % orientacja
            w = message.Pose.Pose.Orientation.W;
            x = message.Pose.Pose.Orientation.X;
            y = message.Pose.Pose.Orientation.Y;
            z = message.Pose.Pose.Orientation.Z;
            % roll pitch yaw
            rot = quat2eul([w x y z]);
            rot = rot(end:-1:1);
            
            position = [pos, rot];
            if strcmp(app.OdomPozycjaSwitch.Value, 'Faktyczna pozycja')
                app.OdomUITable.Data = position;
            end

            % sterowanie pozycyjne
            if isempty(app.goal_positions) == false
                % ustaw kontrolkę
                app.SterowanieaktywneLamp.Color = [0 1 0];

                goal_position = app.goal_positions(1, :);
                position = position([1 2 3 6]); % x y z theta(yaw)
                diff = goal_position-position;
                timestamp = message.Header.Stamp;

                if app.last_msg_timestamp ~= 0
                    dt = timestamp.Sec-app.last_msg_timestamp.Sec + ...
                        (timestamp.Nsec-app.last_msg_timestamp.Nsec)/10e9;
    
                    diff_P = diff;
                    diff_I = app.diff_sum+diff*dt;
                    diff_D = (diff-app.diff_prev)/dt;
                else
                    diff_P = diff;
                    diff_I = 0;
                    diff_D = 0;
                end

                cmd_vel = app.Kp*diff_P + app.Ki*diff_I + app.Kd*diff_D;
                cmd_vel(cmd_vel>1) = 1;
                cmd_vel(cmd_vel<-1) = -1;
                
                % obrót od układu współrzędnych drona
                theta = position(4);
                rot_mat = eye(4);
                rot_mat(1, 1) = cos(theta);
                rot_mat(2, 2) = cos(theta);
                rot_mat(1, 2) = -sin(theta);
                rot_mat(2, 1) = sin(theta);
                cmd_vel = cmd_vel*rot_mat;

                set_cmd_vel(app.cmd_vel_pub, cmd_vel)

                app.diff_prev = diff_P;
                app.diff_sum = diff_I;
                app.last_msg_timestamp = timestamp;

                % pozycja docelowa osiągnięta
%                 if sum(diff(1:3).^2) <= 0.01 && abs(diff(4)) <= 0.1 % 10cm, 5,7deg
                if sum(diff(1:3).^2) <= 0.1 && abs(diff(4)) <= 0.3 % 31,6cm, 17,1deg
                    % usuń osiągniętą pozycję docelową
                    app.goal_positions = app.goal_positions(2:end, :);
                    
                    if isempty(app.goal_positions)
                        % wyłącz kontrolkę
                        app.SterowanieaktywneLamp.Color = [0.6 0.6 0.6];
                        % zresetuj sterowanie pozycyjne
                        app.diff_prev = [0 0 0 0];
                        app.diff_sum = [0 0 0 0];
                        app.last_msg_timestamp = 0;
                        % zatrzymaj drona
                        set_cmd_vel(app.cmd_vel_pub, [0 0 0 0])
                    end
                end
            end
        end
        
        function image_callback(app, ~, message)
            img = readImage(message);
            app.Image.ImageSource = img;
        end
        
        function battery_state_callback(app, ~, message)
            battery_state = message.Percent;
            
            app.battery_state_string = strcat('Poziom baterii: ', int2str(battery_state), '%');

            % zaktualizuj pole tekstowe
            app.StanDronaTextArea.Value = {app.battery_state_string; app.wifi_state_string; app.flying_state_string};
        end
        
        function wifi_state_callback(app, ~, message)
            wifi_state = message.Rssi;

            app.wifi_state_string = strcat('Siła sygnału Wi-Fi: ', int2str(wifi_state), 'dB');

            % zaktualizuj pole tekstowe
            app.StanDronaTextArea.Value = {app.battery_state_string; app.wifi_state_string; app.flying_state_string};
        end
        
        function flying_state_callback(app, ~, message)
            flying_state = message.State;

            switch flying_state
                case 0 % landed
                    flying_state = 'na ziemi';
                case 1 % taking off
                    flying_state = 'startowanie';
                case 2 % hovering
                    flying_state = 'unoszenie';
                case 3 % flying
                    flying_state = 'lot';
                case 4 %landing
                    flying_state = 'lądowanie';
                case 5 % emergency
                    flying_state = 'awaria';
                case 6 % user take off
                    flying_state = 'startowanie użytkownika';
                case 7 % motor ramping
                    flying_state = 'rozruch silników';
                case 8 % emergency landing
                    flying_state = 'lądowanie awaryjne';
            end

            app.flying_state_string = strcat('Stan lotu drona: ', flying_state);
            
            % zaktualizuj pole tekstowe
            app.StanDronaTextArea.Value = {app.battery_state_string; app.wifi_state_string; app.flying_state_string};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %ustaw domyślne IP
            app.IPMasteraEditField.Value = '192.168.0.58';
            % wyzeruj tabelki
            app.OdomUITable.Data = [0, 0, 0, 0, 0, 0];
            app.VelUITable.Data = [0, 0, 0, 0];
            % ustaw stan drona
            app.battery_state_string = 'Poziom baterii: ---%';
            app.wifi_state_string = 'Siła sygnału WiFi: ---dB';
            app.flying_state_string = 'Stan lotu drona: ---';
            app.StanDronaTextArea.Value = {app.battery_state_string; app.wifi_state_string; app.flying_state_string};
        end

        % Button pushed function: PolaczButton
        function PolaczButtonPushed(app, event)
            ros_connect(app.IPMasteraEditField.Value, app.PortEditField.Value)

            app.takeoff_pub = rospublisher('/bebop/takeoff', 'std_msgs/Empty');
            app.land_pub = rospublisher('/bebop/land', 'std_msgs/Empty');
            app.emergency_pub = rospublisher('/bebop/emergency', 'std_msgs/Empty');
            app.cmd_vel_pub = rospublisher('bebop/cmd_vel', 'geometry_msgs/Twist');
            
%             app.odometry_sub = rossubscriber('/bebop/odom', 'nav_msgs/Odometry', @(src, msg) odometry_callback(app, src, msg));
            app.position_sub = rossubscriber('/bebop/odom', 'nav_msgs/Odometry', @(src, msg) position_callback(app, src, msg)); % temat do zmiany!
            app.image_raw_sub = rossubscriber('/bebop/image_raw', 'sensor_msgs/Image', @(src, msg) image_callback(app, src, msg));
            app.battery_state_sub = rossubscriber('/bebop/states/common/CommonState/BatteryStateChanged', ...
                'bebop_msgs/CommonCommonStateBatteryStateChanged', @(src, msg) battery_state_callback(app, src, msg));
            app.wifi_state_sub = rossubscriber('/bebop/states/common/CommonState/WifiSignalChanged', ...
                'bebop_msgs/CommonCommonStateWifiSignalChanged', @(src, msg) wifi_state_callback(app, src, msg));
            app.flying_state_sub = rossubscriber('/bebop/states/ardrone3/PilotingState/FlyingStateChanged', ...
                'bebop_msgs/Ardrone3PilotingStateFlyingStateChanged', @(src, msg) flying_state_callback(app, src, msg));
    
        end

        % Button pushed function: RozlaczButton
        function RozlaczButtonPushed(app, event)
            % wyłącz sterowanie pozycyjne
            app.SterowanieaktywneLamp.Color = [0.6 0.6 0.6];
            app.goal_positions = zeros(0, 4);
            app.diff_prev = [0 0 0 0];
            app.diff_sum = [0 0 0 0];
            app.last_msg_timestamp = 0;
            
            rosshutdown
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            takeoff(app.takeoff_pub)
        end

        % Button pushed function: LadowanieButton
        function LadowanieButtonPushed(app, event)
            % wyłącz sterowanie pozycyjne
            app.SterowanieaktywneLamp.Color = [0.6 0.6 0.6];
            app.goal_positions = zeros(0, 4);
            app.diff_prev = [0 0 0 0];
            app.diff_sum = [0 0 0 0];
            app.last_msg_timestamp = 0;

            land(app.land_pub)
        end

        % Button pushed function: AwariaButton
        function AwariaButtonPushed(app, event)
            % wyłącz sterowanie pozycyjne
            app.SterowanieaktywneLamp.Color = [0.6 0.6 0.6];
            app.goal_positions = zeros(0, 4);
            app.diff_prev = [0 0 0 0];
            app.diff_sum = [0 0 0 0];
            app.last_msg_timestamp = 0;
            
            emergency(app.emergency_pub)
        end

        % Button pushed function: WyslijButton
        function WyslijButtonPushed(app, event)
            switch app.PredkoscPozycjaSwitch.Value
                case 'Zadana prędkość'
                    % wyłącz sterowanie pozycyjne
                    app.SterowanieaktywneLamp.Color = [0.6 0.6 0.6];
                    app.goal_positions = zeros(0, 4);
                    app.diff_prev = [0 0 0 0];
                    app.diff_sum = [0 0 0 0];
                    app.last_msg_timestamp = 0;

                    cmd_vel = app.VelUITable.Data;
                    cmd_vel(cmd_vel>1) = 1;
                    cmd_vel(cmd_vel<-1) = -1;
                    set_cmd_vel(app.cmd_vel_pub, cmd_vel)
                case 'Zadana pozycja'
                    % pozycje docelowe zawierające jeden element
                    app.goal_positions = [app.VelUITable.Data];
            end
        end

        % Button pushed function: EksportujObrazButton
        function EksportujObrazButtonPushed(app, event)
            img = app.Image.ImageSource;
            assignin('base', 'img', img)
        end

        % Value changed function: OdomPozycjaSwitch
        function OdomPozycjaSwitchValueChanged(app, event)
            % value = app.OdomPozycjaSwitch.Value
            % zresetuj tabelę
            app.OdomUITable.Data = [0, 0, 0, 0, 0, 0];
        end

        % Value changed function: PredkoscPozycjaSwitch
        function PredkoscPozycjaSwitchValueChanged(app, event)
            value = app.PredkoscPozycjaSwitch.Value;
            % zresetuj tabelę
            app.VelUITable.Data = [0, 0, 0, 0];
            switch value
                case 'Zadana prędkość'
                    app.VelUITable.ColumnName = {'vx'; 'vy'; 'vz'; 'omega'};
                case 'Zadana pozycja'
                    app.VelUITable.ColumnName = {'x'; 'y'; 'z'; 'theta'};
            end
        end

        % Button pushed function: ZaawansowaneVelButton
        function ZaawansowaneVelButtonPushed(app, event)
            % wyświetl panel
            app.ZaawansowaneopcjesterowaniaPanel.Visible = true;
        end

        % Button pushed function: WczytajButton
        function WczytajButtonPushed(app, event)
            dane = evalin('base', app.nazwazmiennejEditField.Value);
            if size(dane, 2) == 4
                switch app.PredkoscPozycjaSwitch.Value
                    case 'Zadana prędkość'
                        %% TODO
                    case 'Zadana pozycja'
                        app.goal_positions = dane;
                end
            end

            % ukruj panel
            app.ZaawansowaneopcjesterowaniaPanel.Visible = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.IPmasteraEditFieldLabel = uilabel(app.UIFigure);
            app.IPmasteraEditFieldLabel.Text = 'IP mastera';
            app.IPMasteraEditField = uieditfield(app.UIFigure, 'text');
            app.IPMasteraEditField.Placeholder = '192.168.0.58';
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 11311;
            app.PolaczButton = uibutton(app.UIFigure, 'push');
            app.PolaczButton.ButtonPushedFcn = createCallbackFcn(app, @PolaczButtonPushed, true);
            app.PolaczButton.Text = 'Połącz';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LadowanieButton = uibutton(app.UIFigure, 'push');
            app.LadowanieButton.ButtonPushedFcn = createCallbackFcn(app, @LadowanieButtonPushed, true);
            app.LadowanieButton.Text = 'Lądowanie';
            app.AwariaButton = uibutton(app.UIFigure, 'push');
            app.AwariaButton.ButtonPushedFcn = createCallbackFcn(app, @AwariaButtonPushed, true);
            app.AwariaButton.Text = 'Awaria';
            app.OdomUITable = uitable(app.UIFigure);
            app.OdomUITable.ColumnName = {'x'; 'y'; 'z'; 'roll'; 'pitch'; 'yaw'};
            app.OdomUITable.RowName = {};
            app.VelUITable = uitable(app.UIFigure);
            app.VelUITable.ColumnName = {'vx'; 'vy'; 'vz'; 'omega'};
            app.VelUITable.RowName = {};
            app.VelUITable.ColumnEditable = true;
            app.WyslijButton = uibutton(app.UIFigure, 'push');
            app.WyslijButton.ButtonPushedFcn = createCallbackFcn(app, @WyslijButtonPushed, true);
            app.WyslijButton.Text = 'Wyślij';
            app.Image = uiimage(app.UIFigure);
            app.EksportujObrazButton = uibutton(app.UIFigure, 'push');
            app.EksportujObrazButton.ButtonPushedFcn = createCallbackFcn(app, @EksportujObrazButtonPushed, true);
            app.EksportujObrazButton.Text = 'Eksportuj obraz';
            app.RozlaczButton = uibutton(app.UIFigure, 'push');
            app.RozlaczButton.ButtonPushedFcn = createCallbackFcn(app, @RozlaczButtonPushed, true);
            app.RozlaczButton.Text = 'Rozłącz';
            app.StandronaTextArea_2Label = uilabel(app.UIFigure);
            app.StandronaTextArea_2Label.Text = 'Stan drona';
            app.StanDronaTextArea = uitextarea(app.UIFigure);
            app.StanDronaTextArea.Editable = 'off';
            app.ZaawansowaneVelButton = uibutton(app.UIFigure, 'push');
            app.ZaawansowaneVelButton.ButtonPushedFcn = createCallbackFcn(app, @ZaawansowaneVelButtonPushed, true);
            app.ZaawansowaneVelButton.Text = 'Zaawansowane';
            app.ZaawansowaneOdomButton = uibutton(app.UIFigure, 'push');
            app.ZaawansowaneOdomButton.Enable = 'off';
            app.ZaawansowaneOdomButton.Text = 'Zaawansowane';
            app.OdomPozycjaSwitch = uiswitch(app.UIFigure, 'slider');
            app.OdomPozycjaSwitch.Items = {'Odometria', 'Faktyczna pozycja'};
            app.OdomPozycjaSwitch.ValueChangedFcn = createCallbackFcn(app, @OdomPozycjaSwitchValueChanged, true);
            app.OdomPozycjaSwitch.Value = 'Odometria';
            app.PredkoscPozycjaSwitch = uiswitch(app.UIFigure, 'slider');
            app.PredkoscPozycjaSwitch.Items = {'Zadana prędkość', 'Zadana pozycja'};
            app.PredkoscPozycjaSwitch.ValueChangedFcn = createCallbackFcn(app, @PredkoscPozycjaSwitchValueChanged, true);
            app.PredkoscPozycjaSwitch.Value = 'Zadana prędkość';
            app.SterowanieaktywneLampLabel = uilabel(app.UIFigure);
            app.SterowanieaktywneLampLabel.Text = 'Sterowanie aktywne';
            app.SterowanieaktywneLamp = uilamp(app.UIFigure);
            app.ZaawansowaneopcjesterowaniaPanel = uipanel(app.UIFigure);
            app.ZaawansowaneopcjesterowaniaPanel.Title = 'Zaawansowane opcje sterowania';
            app.ZaawansowaneopcjesterowaniaPanel.Visible = 'off';
            app.WczytajButton = uibutton(app.ZaawansowaneopcjesterowaniaPanel, 'push');
            app.WczytajButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajButtonPushed, true);
            app.WczytajButton.Text = 'Wczytaj';
            app.nazwazmiennejEditFieldLabel = uilabel(app.ZaawansowaneopcjesterowaniaPanel);
            app.nazwazmiennejEditFieldLabel.Text = 'nazwa zmiennej:';
            app.nazwazmiennejEditField = uieditfield(app.ZaawansowaneopcjesterowaniaPanel, 'text');
            app.WczytajliststerowazMatlabamacierznx4Label = uilabel(app.ZaawansowaneopcjesterowaniaPanel);
            app.WczytajliststerowazMatlabamacierznx4Label.Text = {'Wczytaj listę sterowań z Matlaba'; '(macierz nx4)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end